classdef BIOENG_2351_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SimulateVitalsPanel             matlab.ui.container.Panel
        SimBobbingEditField             matlab.ui.control.NumericEditField
        SimBobbingEditFieldLabel        matlab.ui.control.Label
        SimCadenceEditField             matlab.ui.control.NumericEditField
        SimCadenceEditFieldLabel        matlab.ui.control.Label
        SimSpO2EditField                matlab.ui.control.NumericEditField
        SimSpO2EditFieldLabel           matlab.ui.control.Label
        SimPaceEditField                matlab.ui.control.NumericEditField
        SimPaceEditFieldLabel           matlab.ui.control.Label
        SimHREditField                  matlab.ui.control.NumericEditField
        SimHREditFieldLabel             matlab.ui.control.Label
        SimulateVitalsSwitch            matlab.ui.control.Switch
        SimulateVitalsSwitchLabel       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        TargetCadenceEditField          matlab.ui.control.NumericEditField
        TargetCadenceEditFieldLabel     matlab.ui.control.Label
        EndWorkoutButton                matlab.ui.control.Button
        SensorsCalibratedLabel          matlab.ui.control.Label
        TargetZoneDropDown              matlab.ui.control.DropDown
        TargetZoneDropDownLabel         matlab.ui.control.Label
        ZonesTable                      matlab.ui.control.Table
        ComputeZonesButton              matlab.ui.control.Button
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Beginthetreadmillatapaceof20minmileandwalknormally2PressStartWorkoutandcontinuetowalknormallyfor5stocalibratethesensorsLabel  matlab.ui.control.Label
        CalibrateSensorsBeginWorkoutLabel  matlab.ui.control.Label
        WelcometoPantheRunTheGreatSpaceEscapeLabel  matlab.ui.control.Label
        DAQStartupStatus                matlab.ui.control.Label
        StartDAQsButton                 matlab.ui.control.Button
        ConnectedDAQStatusLabel         matlab.ui.control.Label
        ArduinoPortStatusLabel          matlab.ui.control.Label
        ChooseWorkoutSettingsLabel      matlab.ui.control.Label
        SelectArduinoPortDropDown       matlab.ui.control.DropDown
        SelectArduinoPortDropDownLabel  matlab.ui.control.Label
        SelectDAQDeviceDropDown         matlab.ui.control.DropDown
        SelectDAQDeviceDropDownLabel    matlab.ui.control.Label
        StartWorkoutButton              matlab.ui.control.Button
        SearchforDAQsButton             matlab.ui.control.Button
        ConnectDataAcquisitionEquipmentLabel  matlab.ui.control.Label
        MainTab                         matlab.ui.container.Tab
        SimToolsButton                  matlab.ui.control.StateButton
        FeedbackPanel                   matlab.ui.container.Panel
        RunningFormPanel                matlab.ui.container.Panel
        FormFeedbackText                matlab.ui.control.Label
        ExertionPanel                   matlab.ui.container.Panel
        CardiovascularFeedbackText      matlab.ui.control.Label
        EndWorkoutButton_2              matlab.ui.control.Button
        EscapetheAliensPanel            matlab.ui.container.Panel
        Image4                          matlab.ui.control.Image
        ufo                             matlab.ui.control.Image
        SpaceShip                       matlab.ui.control.Image
        MetricsPanel                    matlab.ui.container.Panel
        GameInfoText                    matlab.ui.control.Label
        CurrentCadenceLabel             matlab.ui.control.Label
        CurrentCadenceText              matlab.ui.control.Label
        CurrentPaceLabel                matlab.ui.control.Label
        CurrentPaceText                 matlab.ui.control.Label
        CurrentSpO2Label                matlab.ui.control.Label
        CurrentSpO2Text                 matlab.ui.control.Label
        CurrentHRLabel                  matlab.ui.control.Label
        CurrentHRText                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% Simulate Vitals
        simulate_vitals     % Bool to use vital simulation or DAQ devices

        %% DAQ objects and constants:
        selected_daq        % Selected NI DAQ device (e.g. "Dev15")
        selected_ard        % Selected Arduino port (e.g. "COM4")
        nidaq               % Currently running NI DAQ device
        nidaq_fs = 300;     % NI DAQ sampling rate
        arduino             % Currently running arduino device
        ard_fs = 20;        % Arduino sampling rate
        d_hr                % Digital filt object for PPG data
        d_acc               % Digital filt object for acceleration data
        sec_to_plot = 5     % Seconds to plot for

        %% Data buffers (contantly updating):
        spo2 = 0;           % Current blood oxygen value (float)
        ts_acc_buffer       % Current vector of timestamps for acc data
        x_acc_buffer        % Current vector of x-axis acceleration data
        y_acc_buffer        % Current vector of y-axis acceleration data
        mag_acc_buffer      % (is it necessary??)
        ts_ppg              % Current vector of timestamps for ppg data
        ppg                 % Current vector of ppg magnitude data
        heartrate = 0;      % Current heartrate value (float)
        cadence             % Current cadence value (float)
        pace                % Current pace value (float)
        bob_ratio           % Ratio of 2nd/1st frequency component in acc (float)
        
        %% HR Zones
        HRmax double = NaN               % computed with 220 - age
        Zones table                      % full zones table (text + numeric)
        TargetZone struct = struct('name',"", 'pct',[NaN NaN], 'bpm',[NaN NaN])
        target_cadence = 180;  % Target cadence- default is 180;

        %% Accelerometer Calibration
        isCalibrating = true   % Bool for state of calibration
        pace_calib             % Calibration value for pace

        %% Race & Performance Algorithm Properties
        race_zone = 0;      % Current race zone
        S_hr                % Mapping vector for heartrate
        S_spo2              % Mapping vector for spo2
        S_cadence           % Mapping vector for cadence
        S_bobbing           % Mapping vector for bobbing
       
        %% Datasave Buffers
        ds_acc = [];        % Preallocated data arrays for entire record
        ds_hr = [];
        ds_spo2 = [];

        ryg_cmap;           % Colormap for red-yellow-green for feedback
        
    end
    
    methods (Access = private)
        % ---------------------------------------
        %  MAIN FUNCTION
        % ---------------------------------------

        function mainFcn(app, src)
            % Main callback for ScansAvailableFcn: continuous loop

            %% NI DAQ Acquisition:
            [data, ts, ~] = read(src, src.ScansAvailableFcnCount, OutputFormat='Matrix');
            try
                % Acquire data and update buffers:
                x_acc = (data(:,1) - 3/2) ./ 0.42; %according to formula based on Vcc and volts per g
                y_acc = (data(:,2) - 3/2) ./ 0.42;
                mag_acc = sqrt(x_acc.^2 + y_acc.^2); 

                app.x_acc_buffer = [app.x_acc_buffer; x_acc];
                app.y_acc_buffer = [app.y_acc_buffer; y_acc];
                app.ts_acc_buffer = [app.ts_acc_buffer; ts];
                app.mag_acc_buffer = [app.mag_acc_buffer; mag_acc];

                app.ds_acc = [app.ds_acc; ts, x_acc, y_acc, mag_acc];
            catch ME
                fprintf("\n\tError in NIDAQ data acquisition:");
                fprintf("\n\tMessage: %s", ME.message);
            end

            %% Arduino Acquisition:
            if app.arduino.NumBytesAvailable > 0
                try 
                    % Acquire data and update buffers:
                    line = readline(app.arduino);
                    ard_data = str2double(split(line, ','));
                    validateSpO2(app, ard_data(end));

                    app.ppg = ard_data(1:end-1);
                    app.ts_ppg = linspace(app.ts_acc_buffer(end)-length(app.ppg) ...
                        /app.ard_fs, app.ts_acc_buffer(end), length(app.ppg));
                catch ME
                    fprintf("\n\tError in Arduino data acquisition:");
                    fprintf("\n\tMessage: %s", ME.message);
                end
            end

            %% Process Data:
            % Here update plots and compute metrics:
            if ~isempty(app.ppg)
                pre_hr = heartRateFcn(app.ts_ppg, app.ppg, app.d_hr);
                if isnumeric(pre_hr) && ~isnan(pre_hr) % avoid issues if returns NaN
                    app.heartrate = heartRateFcn(app.ts_ppg, app.ppg, app.d_hr); 
                    app.ds_hr = [app.ds_hr; app.ts_ppg(end), app.heartrate];
                end

                if app.simulate_vitals == 1
                    app.heartrate = app.SimHREditField.Value;
                end

                try
                    app.CurrentHRLabel.Text = string(app.heartrate);
                    % for some reason loves to throw some unexplainable
                    % error so idk
                end
            end

            if ~isempty(app.x_acc_buffer)
                filtered_y = filtfilt(app.d_acc, app.y_acc_buffer);
                app.cadence = cadenceFcn(app.ts_acc_buffer, filtered_y);
                app.bob_ratio = computeBobRatio(app, filtered_y);

                if app.simulate_vitals == 1
                    app.cadence = app.SimCadenceEditField.Value;
                    app.bob_ratio = app.SimBobbingEditField.Value;
                end

                app.CurrentCadenceLabel.Text = string(app.cadence);
            end

            if ~isempty(app.spo2)
                if isnumeric(app.spo2)
                    app.CurrentSpO2Label.Text = string(app.spo2);
                end
            end

            if app.isCalibrating == true && length(app.ts_acc_buffer) >= 5*app.nidaq_fs
                filtered_acc = filtfilt(app.d_acc, app.mag_acc_buffer);
                app.pace_calib = calibrateAccelerometerFcn(app.ts_acc_buffer, filtered_acc, 20);
                app.isCalibrating = false;
                app.SensorsCalibratedLabel.Visible = 'on';
                %disp(app.pace_calib);
            elseif app.isCalibrating == false
                % should probably move this to the update buffers function so
                % that it's not being called every single time. once every
                % 5s when the buffer fills might be fine instead
                filtered_acc = filtfilt(app.d_acc, app.mag_acc_buffer);
                app.pace = paceFcn(app.ts_acc_buffer, filtered_acc, app.pace_calib);
                
                if app.simulate_vitals == 1
                    app.pace = app.SimPaceEditField.Value;
                end

                app.CurrentPaceLabel.Text = string(app.pace); % could add some logic to convert from a numeric -> min+sec (8.5 -> 8:30)
            end

            % Call update fcns that slide the data buffer window
            updatePPGBuffers(app);
            updateAccBuffers(app);

            %% Main Algorithm:
            if app.isCalibrating == false
                raceAlgorithm(app) % call raceAlgorithm as its own method to separate it so it can edit properties of app
            end
        end
        
        % ---------------------------------------
        %  Helper Functions
        % ---------------------------------------

        function hrmax = computeHRmax_220minusAge(app, age)
            % Enforce reasonable bounds
            age = max(10, min(100, double(age)));
            hrmax = 220 - age;
        end

        function Z = makeZones(app, hrmax)
            % Percent bands 
            names = ["Very Light","Light","Moderate","Hard","Maximum"]';
            pct    = [0.50 0.60; 0.60 0.70; 0.70 0.80; 0.80 0.90; 0.90 1.00];
            desc   = [ ...
                "Helps with recovery"; ...
                "Improves basic endurance / fat burning (easy jog / brisk walk)"; ...
                "Improves aerobic fitness (steady run)"; ...
                "Increases max capacity (tempo/intervals)"; ...
                "Develops speed (sprints / very hard)" ...
            ];
            bpm = round(pct * hrmax);
            pctStr   = arrayfun(@(i)sprintf('%d–%d%%',round(100*pct(i,1)),round(100*pct(i,2))), (1:5)','uni',0);
            rangeStr = arrayfun(@(i)sprintf('%d–%d',bpm(i,1),bpm(i,2)), (1:5)','uni',0);
    
            Z = table(names, pctStr, rangeStr, desc, ...
                'VariableNames', {'Zone','PercentHRmax','Range_bpm','Intensity'});
            % Keep numeric copies for control logic
            Z.NumericPct = pct;   % 5x2
            Z.NumericBpm = bpm;   % 5x2
        end
    
        function colorizeZoneRows(app)
            % Apply row colors to ZonesTable using uistyle
            T = app.ZonesTable;
            removeStyle(T) % clear old styles
            colors = [...
                0.85 0.85 0.85;  % Very Light (gray)
                0.80 0.87 1.00;  % Light (blue)
                0.83 0.93 0.83;  % Moderate (green)
                1.00 0.94 0.75;  % Hard (yellow)
                1.00 0.80 0.80]; % Maximum (red)
            for r = 1:size(app.Zones,1)
                addStyle(T, uistyle('BackgroundColor', colors(r,:)), 'row', r);
            end
        end
    
        function setTargetFromDropdown(app)
            if isempty(app.Zones) || any(ismissing(app.Zones.Zone))
                return
            end
            % Map dropdown to row index
            map = containers.Map(...
                ["Very Light (50-60%)","Light (60-70%)","Moderate (70-80%)","Hard (80-90%)","Maximum (90-100%)"], ...
                num2cell(1:5));
            idx = map(string(app.TargetZoneDropDown.Value));
            app.TargetZone.name = string(app.Zones.Zone(idx));
            app.TargetZone.pct  = app.Zones.NumericPct(idx,:);
            app.TargetZone.bpm  = app.Zones.NumericBpm(idx,:);
        end

        function createHRFilter(app)
            % Design a highpass filter for PPG data
            %
            % Returns digital filter object d_hr

            Fs = app.ard_fs;
            Fc = 0.4;
        
            app.d_hr = designfilt('highpassiir', ...
                'FilterOrder', 3, ...
                'HalfPowerFrequency', Fc, ...
                'SampleRate', Fs, ...
                'DesignMethod', 'Butter');
        end
        
        function createAccFilter(app)
            % Design a filter for the acceleration data
            %
            % Returns digital filter object d_acc

            Fs = app.nidaq_fs;
            Fc = 6;

            app.d_acc = designfilt('lowpassiir', ...
                'FilterOrder', 3, ...
                'HalfPowerFrequency', Fc, ...
                'SampleRate', Fs, ...
                'DesignMethod', 'Butter');
        end

        function updatePPGBuffers(app)
            % Handles PPG data buffer sliding window

            %filt_ppg = filtfilt(app.d_hr, app.ppg);
            %plot(app.HRAxes, app.ts_ppg, filt_ppg);
            % Plotting is depracated in final app

            if length(app.ppg) > (1+app.sec_to_plot)*app.ard_fs
                app.ppg = app.ppg(app.ard_fs:end);
                app.ts_ppg = app.ts_ppg(app.ard_fs:end);
            end
            
            %xlim(app.HRAxes, "tight");
            %ylim(app.HRAxes, "tight");
        end

        function updateAccBuffers(app)
            % Handles acceleration data buffer updating

            %filt_acc = filtfilt(app.d_acc, app.y_acc_buffer);
            %plot(app.CadenceAxes, app.ts_acc_buffer, filt_acc);
            % Plotting is depracated in final app

            if length(app.y_acc_buffer) > (1+app.sec_to_plot)*app.nidaq_fs
                app.y_acc_buffer = app.y_acc_buffer(app.nidaq_fs:end);
                app.x_acc_buffer = app.x_acc_buffer(app.nidaq_fs:end);
                app.ts_acc_buffer = app.ts_acc_buffer(app.nidaq_fs:end);
                app.mag_acc_buffer = app.mag_acc_buffer(app.nidaq_fs:end);
            end
            
            %if app.ts_acc_buffer(1) >= app.sec_to_plot

            %    xlim(app.CadenceAxes, [app.ts_acc_buffer(end-app.sec_to_plot*app.nidaq_fs), app.ts_acc_buffer(end)])

            %end
            %ylim(app.CadenceAxes, "tight")
        end
        
        function validateSpO2(app, spo2)
            % Validate spo2 data input to avoid NaN or -999 values

            if spo2 > 0 && spo2 <= 100
                app.spo2 = spo2;    
                app.ds_spo2 = [app.ds_spo2; app.ts_ppg(end), app.spo2];
            elseif app.simulate_vitals == 1
                app.spo2 = app.SimSpO2EditField.Value;
            end
        end
        
        function raceAlgorithm(app)
            % Main algorithm handler for the race:
            %
            % The algorithm continuously evaluates the user's latest
            % metrics. These metrics are mapped into a normalized
            % "performance score" using non-linear transformations. This
            % rewards performance closer to the user's target, while
            % punishing both under- and over-exertion. 
            %
            % After transformation, the metrics are combined into a
            % performance index in the range [-1 1], which is converted
            % into a velocity that controls the game environment.
            %
            % Additionally, for certain metrics, the algorithm will return
            % feedback to the user.

            % wrap everything in try statements in case of missing data
            % and default assign to 0
            [hr_score, cadence_score, spo2_score, bobbing_score] = deal(0);

            try
                %disp(app.heartrate);
                
                hr_score = app.S_hr(round(app.heartrate));
                %disp(app.hr_score);
                if ~isnumeric(hr_score)
                    hr_score = 0;
                end
            catch
                hr_score = 0;
            end
            try
                cadence_score = app.S_cadence(round(app.cadence));
                if ~isnumeric(cadence_score)
                    cadence_score = 0;
                end
            catch
                cadence_score = 0;
            end
            try
                spo2_score = app.S_spo2(round(app.spo2));
                if ~isnumeric(spo2_score)
                    spo2_score = 0;
                end
            catch
                spo2_score = 0;
            end
            try
                bobbing_score = app.S_bobbing(round(app.bob_ratio*100));
                if ~isnumeric(bobbing_score)
                    bobbing_score = 0;
                end
            catch
                bobbing_score = 0;
            end

            %{
            fprintf("\nHR Score: %.2d", hr_score);
            disp(type(hr_score));
            fprintf("\nCadence Score: %.2d", cadence_score)
            disp(type(cadence_score));
            fprintf("\nSpO2 Score: %.2d", spo2_score);
            disp(type(spo2_score));
            fprintf("\nBobbing Score: %.2d", bobbing_score);
            disp(type(bobbing_score));
            %}

            %{
            disp(hr_score);
            disp(cadence_score);
            disp(spo2_score);
            disp(bobbing_score);
            %}

            try
                % weigh each metric's score contribution
                cumulative_score = ...
                    0.5*hr_score +...
                    0.2*cadence_score+...
                    0.2*bobbing_score+...
                    0.1*spo2_score;
            catch
                % if SOMEHOW one of the vals is NaN it will return
                % nothing!! so infuriating
            end

            if isnan(cumulative_score)
                % catches some odd-ball exception where a score may not be
                % assigned if any of the above return NaN for example
                cumulative_score = 0;
            end
            %disp(cumulative_score);

            % Update spaceship position:

            speed = 3; % adjust to scale time it will take to reach either side
            
            pos = app.SpaceShip.Position;  % [x y w h]
            
            pos(1) = pos(1) + speed * cumulative_score; % Update X position
            pos(1) = min(max(pos(1), 81), 507); % Clamp to region btw ufo + earth
            
            verticalAmplitude = 1.5;       % add some random up and down wobble
            verticalShift = (rand() - 0.5) * 2 * verticalAmplitude;
            
            pos(2) = pos(2) + verticalShift; % update Y position
            pos(2) = min(max(pos(2), 50), 350); % clamp Y

            ufo_pos = app.ufo.Position; % apply the same wobble to the UFO
            ufo_pos(2) = ufo_pos(2) - verticalShift;
            ufo_pos(2) = min(max(pos(2), 50), 350);
            
            %% --- Write back ---
            app.SpaceShip.Position = pos;
            app.ufo.Position = ufo_pos;

            %% Feedback handling:
            cv_score = 0.8*hr_score + 0.2*spo2_score;
            cv_idx = round((cv_score+1)/2*255)+1; % convert to colormap index
            cv_idx = min(max(cv_idx,1),256); % clamp index to colormap lims
            cv_color = app.ryg_cmap(cv_idx,:);
            app.ExertionPanel.BackgroundColor = cv_color;
            if cv_score >= 0.3
                app.CardiovascularFeedbackText.Text = "Great job! Keep going!";
            elseif cv_score < 0.3
                if app.spo2 < 95
                    app.CardiovascularFeedbackText.Text = "Low SpO2: Remember to fully inhale and exhale!";
                elseif app.heartrate > mean(app.TargetZone.bpm)
                    app.CardiovascularFeedbackText.Text = "High Heart Rate: Slow your pace to recover and avoid overexertion!";
                else
                    app.CardiovascularFeedbackText.Text = "Heart Rate below Target Zone: Increase your pace to reach your target exertion level!";
                end
            end

            form_score = 0.5*bobbing_score+0.5*cadence_score;
            form_idx = round((form_score+1)/2*255)+1; % convert to colormap index
            form_idx = min(max(form_idx,1),256); % clamp index to colormap lims
            form_color = app.ryg_cmap(form_idx,:);
            app.RunningFormPanel.BackgroundColor = form_color;
            if form_score >= 0.5
                app.FormFeedbackText.Text = "Great job! Keep going!";
            elseif form_score < 0.5
                if bobbing_score < 0
                    app.FormFeedbackText.Text = "Bobbing Up and Down Detected: Avoid bobbing up and down to increase running efficiency!";
                elseif app.cadence > app.target_cadence + 10
                    app.FormFeedbackText.Text = "High Cadence: Increase stride length to avoid risk of injury!";
                elseif app.cadence < app.target_cadence - 10
                    app.FormFeedbackText.Text = "Low Cadence: Decrease stride length to avoid risk of injury!";
                else
                    app.FormFeedbackText.Text = "Unexpected condition";
                end

            end

        end
        
        function createSVectors(app)
            % Function to create the "S vectors" which map physiological
            % input params to performance scores.
            %
            % Stored as properties of the app to enable easy accessing.
            %
            % S vectors are all normalized to a range of [-1 1]

            % HR:
            % HR uses a normal probability density fcn (gaussian) to create
            % a bell shaped distribution. 
            hr_vector = linspace(1, 230, 230);
            app.S_hr = exp(-0.5 * ((hr_vector - mean(app.TargetZone.bpm)) ./ 10).^2) ...
                       ./ (10 * sqrt(2*pi));
            app.S_hr = app.S_hr/(0.5*max(app.S_hr)) - 1; % normalize to [-1, 1]

            % HR:
            % Cadence also uses a normal probability density fcn (gaussian) to create
            % a bell shaped distribution. 
            cadence_vector = linspace(1,250,250);
            app.S_cadence = exp(-0.5 * ((cadence_vector - app.target_cadence) ./ 30).^2) ...
                ./ (30 * sqrt(2*pi)); 
            app.S_cadence = app.S_cadence/(0.5*max(app.S_cadence)) - 1;

            % SpO2:
            % SpO2 uses an inverted "z" shape to linearly ramp from -1 to 1 in the
            % range of SpO2 = 90 to 95.
            app.S_spo2 = [-1*ones(1,89), linspace(-1, 1, 6), ones(1,5)];

            % Bobbing:
            % Bobbing uses an inverted sigmoidal function to smoothly go
            % from 1 to -1. Indexed as a percent.
            bobbing_vector = linspace(0,1,100);
            app.S_bobbing = -2./(1+exp(-10*(bobbing_vector-0.5)))+1;
        end
        
        function bob_ratio = computeBobRatio(app, data)
            % Compute the 'bobbing ratio': ratio of second highest to max
            % frequency in accelerometer y-axis data
            %
            % Bob ratio is an indication of running efficiency: excessive
            % bobbing up and down (higher bob_raito) is less efficient.

            L = length(data);
            Y = fft(data);
            P2 = abs(Y/L);
            P1 = P2(1:round(L/2)+1);
            P1 = P1(5:end); % ignore DC components
            f = app.nidaq_fs*(0:L/2) / L;

            [~, max_idx] = max(P1);
            max_freq = f(max_idx);

            fft_cadence = 60*max_freq;
            %fprintf("\nCadence from FFT method: %d\n",fft_cadence)

            P1_lowfreqs = P1(1:round(max_idx*3/4)); % get window from 0-3/4 of max index
            [~, second_idx] = max(P1_lowfreqs); % find second highest frequency;
            second_freq = P1_lowfreqs(second_idx);

            bob_ratio = second_freq/max_freq;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchforDAQsButton
        function searchForDAQs(app, event)
            % Search for DAQs and returns available COM (Serial) and DAQ
            % objects for user to select

            % Enable dropdown menus once button pressed:
            app.SelectDAQDeviceDropDown.Enable = "on";
            app.SelectArduinoPortDropDown.Enable = "on";

            % DAQ handler:
            avail_daqs = daqlist;
            if isempty(avail_daqs)
                app.ConnectedDAQStatusLabel.Text = 'No connected DAQ devices found.';
            else
                device_ids = avail_daqs.DeviceID;
                app.SelectDAQDeviceDropDown.Items = ["<Select>"; device_ids];
            end
            
            % Serial (Arduino) handler:
            avail_coms = serialportlist;
            if isempty(avail_coms)
                app.ArduinoPortStatusLabel.Text = 'No active COM (USB) ports found.';
            else
                app.SelectArduinoPortDropDown.Items = ["<Select>", avail_coms];
            end

            % Once user makes selections, let them start the DAQs
            app.StartDAQsButton.Enable = "on";
        end

        % Button pushed function: StartDAQsButton
        function daqSetup(app, event)
            % Connects to selected DAQ/Arduino devices
            try
                % Attempt connection
                app.nidaq = daq("ni");
                addinput(app.nidaq, app.selected_daq, "ai0", "Voltage");
                addinput(app.nidaq, app.selected_daq, "ai4", "Voltage");
               
                app.nidaq.Rate = app.nidaq_fs;
                app.nidaq.ScansAvailableFcnCount = app.nidaq_fs/10; % effective plot refresh rate
    
                app.arduino = serialport(app.selected_ard, 230400);
                configureTerminator(app.arduino, "LF");
                flush(app.arduino);
    
                app.DAQStartupStatus.FontColor = [0.23,0.67,0.20];
                app.DAQStartupStatus.Text = 'DAQs Connected Successfully';
                app.SearchforDAQsButton.Enable = "off"; % Disable options
                app.StartDAQsButton.Enable = "off";
                app.SelectArduinoPortDropDown.Enable = "off";
                app.SelectDAQDeviceDropDown.Enable = "off";
                app.StartWorkoutButton.Enable ="on";
            catch
                app.DAQStartupStatus.FontColor = [1 0 0];
                app.DAQStartupStatus.Text = "Error encountered: Check selected ports/devices";
            end
        end

        % Value changed function: SelectDAQDeviceDropDown
        function daqSelectionFcn(app, event)
            value = app.SelectDAQDeviceDropDown.Value;
            app.selected_daq = string(value);
        end

        % Value changed function: SelectArduinoPortDropDown
        function ardSelectionFcn(app, event)
            value = app.SelectArduinoPortDropDown.Value;
            app.selected_ard = string(value);
        end

        % Button pushed function: StartWorkoutButton
        function startWorkout(app, event)
            app.nidaq.ScansAvailableFcn = @(src, evt) mainFcn(app, src);
            start(app.nidaq, "continuous");
            
            % Setup function calls:
            createHRFilter(app);
            createAccFilter(app);
            createSVectors(app);
            %app.SimulateVitalsPanel.Visible = 'on'; %handler moved to
            %separate state button callback

            app.StartWorkoutButton.Enable = 'off';
            app.EndWorkoutButton.Enable = 'on';      % setup tab button
            app.EndWorkoutButton_2.Enable = 'on';    % main tab button

            % create red-yellow-green cmap;
            x = [0 0.5 1];
            y = [1 0 0; 1 1 0; 0 1 0];
            app.ryg_cmap = interp1(x, y, linspace(0,1,256));
           
        end

        % Button pushed function: ComputeZonesButton
        function ComputeZonesButtonPushed(app, event)
            age = app.AgeEditField.Value;
            if isempty(age) || ~isfinite(age) || age <= 0
                uialert(app.UIFigure,'Enter a valid age (>0).','Age needed'); 
                return;
            end
        
            % ---- 220 - age method ----
            app.HRmax = app.computeHRmax_220minusAge(age);
        
            % Build zones, show in table, color rows
            app.Zones = app.makeZones(app.HRmax);
            app.ZonesTable.Data = app.Zones(:,1:4);  % text columns only
            app.colorizeZoneRows();
        
            % Default target zone = Moderate
            app.TargetZoneDropDown.Value = "Moderate (70-80%)";
            app.setTargetFromDropdown();
        end

        % Value changed function: TargetZoneDropDown
        function TargetZoneDropDownValueChanged(app, event)
             if isnan(app.HRmax); return; end
             app.setTargetFromDropdown();
            
        end

        % Button pushed function: EndWorkoutButton, EndWorkoutButton_2
        function endWorkoutCallback(app, event)
            stop(app.nidaq);
            flush(app.nidaq);
            flush(app.arduino);

            ds_struct.acc = app.ds_acc;
            ds_struct.hr = app.ds_hr;
            ds_struct.spo2 = app.ds_spo2;

            save("Datasave","ds_struct");
        end

        % Value changed function: SimulateVitalsSwitch
        function simulateVitalSwitchCallback(app, event)
            value = app.SimulateVitalsSwitch.Value;
            if value == "Off"
                app.simulate_vitals = 0;
            elseif value == "On"
                app.simulate_vitals = 1;
            end
        end

        % Value changed function: TargetCadenceEditField
        function updateTargetCadenceCallback(app, event)
            value = app.TargetCadenceEditField.Value;
            app.target_cadence = round(value);
        end

        % Value changed function: SimToolsButton
        function toggleSimTabCallback(app, event)
            value = app.SimToolsButton.Value;
            if value == 1
                app.SimulateVitalsPanel.Visible = "on";
            elseif value == 0
                app.SimulateVitalsPanel.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ConnectDataAcquisitionEquipmentLabel = uilabel(app.SetupTab);
            app.ConnectDataAcquisitionEquipmentLabel.Text = 'Connect Data Acquisition Equipment';
            app.SearchforDAQsButton = uibutton(app.SetupTab, 'push');
            app.SearchforDAQsButton.ButtonPushedFcn = createCallbackFcn(app, @searchForDAQs, true);
            app.SearchforDAQsButton.Text = 'Search for DAQs';
            app.StartWorkoutButton = uibutton(app.SetupTab, 'push');
            app.StartWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @startWorkout, true);
            app.StartWorkoutButton.Enable = 'off';
            app.StartWorkoutButton.Text = 'Start Workout';
            app.SelectDAQDeviceDropDownLabel = uilabel(app.SetupTab);
            app.SelectDAQDeviceDropDownLabel.Enable = 'off';
            app.SelectDAQDeviceDropDownLabel.Text = 'Select DAQ Device';
            app.SelectDAQDeviceDropDown = uidropdown(app.SetupTab);
            app.SelectDAQDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @daqSelectionFcn, true);
            app.SelectDAQDeviceDropDown.Enable = 'off';
            app.SelectDAQDeviceDropDown.Tooltip = {'Select the DAQ device to use (e.g. "Dev15").'};
            app.SelectArduinoPortDropDownLabel = uilabel(app.SetupTab);
            app.SelectArduinoPortDropDownLabel.Enable = 'off';
            app.SelectArduinoPortDropDownLabel.Text = 'Select Arduino Port';
            app.SelectArduinoPortDropDown = uidropdown(app.SetupTab);
            app.SelectArduinoPortDropDown.ValueChangedFcn = createCallbackFcn(app, @ardSelectionFcn, true);
            app.SelectArduinoPortDropDown.Enable = 'off';
            app.SelectArduinoPortDropDown.Tooltip = {'Select the COM (USB) port to which the Arduino is connected (e.g. "COM4").'};
            app.ChooseWorkoutSettingsLabel = uilabel(app.SetupTab);
            app.ChooseWorkoutSettingsLabel.Text = 'Choose Workout Settings';
            app.ArduinoPortStatusLabel = uilabel(app.SetupTab);
            app.ArduinoPortStatusLabel.Text = '';
            app.ConnectedDAQStatusLabel = uilabel(app.SetupTab);
            app.ConnectedDAQStatusLabel.Text = '';
            app.StartDAQsButton = uibutton(app.SetupTab, 'push');
            app.StartDAQsButton.ButtonPushedFcn = createCallbackFcn(app, @daqSetup, true);
            app.StartDAQsButton.Enable = 'off';
            app.StartDAQsButton.Text = 'Start DAQs';
            app.DAQStartupStatus = uilabel(app.SetupTab);
            app.DAQStartupStatus.Text = '';
            app.WelcometoPantheRunTheGreatSpaceEscapeLabel = uilabel(app.SetupTab);
            app.WelcometoPantheRunTheGreatSpaceEscapeLabel.Text = 'Welcome to PantheRun: The Great Space Escape!';
            app.CalibrateSensorsBeginWorkoutLabel = uilabel(app.SetupTab);
            app.CalibrateSensorsBeginWorkoutLabel.Text = 'Calibrate Sensors & Begin Workout';
            app.Beginthetreadmillatapaceof20minmileandwalknormally2PressStartWorkoutandcontinuetowalknormallyfor5stocalibratethesensorsLabel = uilabel(app.SetupTab);
            app.Beginthetreadmillatapaceof20minmileandwalknormally2PressStartWorkoutandcontinuetowalknormallyfor5stocalibratethesensorsLabel.Text = {'1. Begin the treadmill at a pace of 20 min/mile and walk normally.'; '2. Press Start Workout and continue to walk normally for 5s to calibrate the sensors.'};
            app.Image = uiimage(app.SetupTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'Sensor Setup Pic.png');
            app.AgeEditFieldLabel = uilabel(app.SetupTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.SetupTab, 'numeric');
            app.AgeEditField.Limits = [0 Inf];
            app.AgeEditField.RoundFractionalValues = 'on';
            app.ComputeZonesButton = uibutton(app.SetupTab, 'push');
            app.ComputeZonesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeZonesButtonPushed, true);
            app.ComputeZonesButton.Text = 'Compute Zones';
            app.ZonesTable = uitable(app.SetupTab);
            app.ZonesTable.ColumnName = {'Zone'; '%HRmax'; 'Range (bpm)'; 'Intensity/Activity'};
            app.ZonesTable.RowName = {};
            app.ZonesTable.ColumnEditable = [false false false false];
            app.TargetZoneDropDownLabel = uilabel(app.SetupTab);
            app.TargetZoneDropDownLabel.Text = 'Target Zone';
            app.TargetZoneDropDown = uidropdown(app.SetupTab);
            app.TargetZoneDropDown.Items = {'Very Light (50-60%)', 'Light (60-70%)', 'Moderate (70-80%)', 'Hard (80-90%)', 'Maximum (90-100%)'};
            app.TargetZoneDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetZoneDropDownValueChanged, true);
            app.TargetZoneDropDown.Value = 'Very Light (50-60%)';
            app.SensorsCalibratedLabel = uilabel(app.SetupTab);
            app.SensorsCalibratedLabel.Visible = 'off';
            app.SensorsCalibratedLabel.Text = 'Sensors calibrated successfully: please begin your workout!';
            app.EndWorkoutButton = uibutton(app.SetupTab, 'push');
            app.EndWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @endWorkoutCallback, true);
            app.EndWorkoutButton.Enable = 'off';
            app.EndWorkoutButton.Text = 'End Workout';
            app.TargetCadenceEditFieldLabel = uilabel(app.SetupTab);
            app.TargetCadenceEditFieldLabel.Text = 'Target Cadence';
            app.TargetCadenceEditField = uieditfield(app.SetupTab, 'numeric');
            app.TargetCadenceEditField.ValueChangedFcn = createCallbackFcn(app, @updateTargetCadenceCallback, true);
            app.TargetCadenceEditField.Value = 180;
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.MetricsPanel = uipanel(app.MainTab);
            app.MetricsPanel.Title = 'Metrics';
            app.CurrentHRText = uilabel(app.MetricsPanel);
            app.CurrentHRText.Text = 'Current Heart Rate';
            app.CurrentHRLabel = uilabel(app.MetricsPanel);
            app.CurrentHRLabel.Text = '<Placeholder>';
            app.CurrentSpO2Text = uilabel(app.MetricsPanel);
            app.CurrentSpO2Text.Text = 'Current SpO2';
            app.CurrentSpO2Label = uilabel(app.MetricsPanel);
            app.CurrentSpO2Label.Text = '<Placeholder>';
            app.CurrentPaceText = uilabel(app.MetricsPanel);
            app.CurrentPaceText.Text = 'Current Pace';
            app.CurrentPaceLabel = uilabel(app.MetricsPanel);
            app.CurrentPaceLabel.Text = '<Placeholder>';
            app.CurrentCadenceText = uilabel(app.MetricsPanel);
            app.CurrentCadenceText.Text = 'Current Cadence';
            app.CurrentCadenceLabel = uilabel(app.MetricsPanel);
            app.CurrentCadenceLabel.Text = '<Placeholder>';
            app.GameInfoText = uilabel(app.MetricsPanel);
            app.GameInfoText.Text = 'Escape the aliens and get back to Earth! Your performance while running within your target metrics will help you get home! Remember to not over-exert yourself and maintain good running form.';
            app.EscapetheAliensPanel = uipanel(app.MainTab);
            app.EscapetheAliensPanel.Title = 'Escape the Aliens!';
            app.SpaceShip = uiimage(app.EscapetheAliensPanel);
            app.SpaceShip.ImageSource = fullfile(pathToMLAPP, 'assets', 'spaceship.png');
            app.ufo = uiimage(app.EscapetheAliensPanel);
            app.ufo.ImageSource = fullfile(pathToMLAPP, 'assets', 'ufo.png');
            app.Image4 = uiimage(app.EscapetheAliensPanel);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'assets', 'earth.png');
            app.EndWorkoutButton_2 = uibutton(app.MainTab, 'push');
            app.EndWorkoutButton_2.ButtonPushedFcn = createCallbackFcn(app, @endWorkoutCallback, true);
            app.EndWorkoutButton_2.Enable = 'off';
            app.EndWorkoutButton_2.Text = 'End Workout';
            app.FeedbackPanel = uipanel(app.MainTab);
            app.FeedbackPanel.Title = 'Feedback';
            app.ExertionPanel = uipanel(app.FeedbackPanel);
            app.ExertionPanel.Title = 'Exertion';
            app.CardiovascularFeedbackText = uilabel(app.ExertionPanel);
            app.CardiovascularFeedbackText.Text = 'Placeholder... this should update as soon as stats become available';
            app.RunningFormPanel = uipanel(app.FeedbackPanel);
            app.RunningFormPanel.Title = 'Running Form';
            app.FormFeedbackText = uilabel(app.RunningFormPanel);
            app.FormFeedbackText.Text = 'Placeholder... this should update as soon as stats become available';
            app.SimToolsButton = uibutton(app.MainTab, 'state');
            app.SimToolsButton.ValueChangedFcn = createCallbackFcn(app, @toggleSimTabCallback, true);
            app.SimToolsButton.Text = 'Sim Tools';
            app.SimulateVitalsPanel = uipanel(app.UIFigure);
            app.SimulateVitalsPanel.Title = 'Simulate Vitals';
            app.SimulateVitalsPanel.Visible = 'off';
            app.SimulateVitalsSwitchLabel = uilabel(app.SimulateVitalsPanel);
            app.SimulateVitalsSwitchLabel.Text = 'Simulate Vitals';
            app.SimulateVitalsSwitch = uiswitch(app.SimulateVitalsPanel, 'slider');
            app.SimulateVitalsSwitch.ValueChangedFcn = createCallbackFcn(app, @simulateVitalSwitchCallback, true);
            app.SimHREditFieldLabel = uilabel(app.SimulateVitalsPanel);
            app.SimHREditFieldLabel.Text = 'Sim HR';
            app.SimHREditField = uieditfield(app.SimulateVitalsPanel, 'numeric');
            app.SimPaceEditFieldLabel = uilabel(app.SimulateVitalsPanel);
            app.SimPaceEditFieldLabel.Text = 'Sim Pace';
            app.SimPaceEditField = uieditfield(app.SimulateVitalsPanel, 'numeric');
            app.SimSpO2EditFieldLabel = uilabel(app.SimulateVitalsPanel);
            app.SimSpO2EditFieldLabel.Text = 'Sim SpO2';
            app.SimSpO2EditField = uieditfield(app.SimulateVitalsPanel, 'numeric');
            app.SimCadenceEditFieldLabel = uilabel(app.SimulateVitalsPanel);
            app.SimCadenceEditFieldLabel.Text = 'Sim Cadence';
            app.SimCadenceEditField = uieditfield(app.SimulateVitalsPanel, 'numeric');
            app.SimBobbingEditFieldLabel = uilabel(app.SimulateVitalsPanel);
            app.SimBobbingEditFieldLabel.Text = 'Sim Bobbing';
            app.SimBobbingEditField = uieditfield(app.SimulateVitalsPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BIOENG_2351_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end