classdef BIOENG_2351_Project_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        maybeinthisareawecouldhavesomephotostextexplaininghowtowearconnectthesensorsLabel  matlab.ui.control.Label
        WelcometoPantheRunpleaseletscomeupwithadecentnameLabel  matlab.ui.control.Label
        DAQStartupStatus                matlab.ui.control.Label
        StartDAQsButton                 matlab.ui.control.Button
        ConnectedDAQStatusLabel         matlab.ui.control.Label
        ArduinoPortStatusLabel          matlab.ui.control.Label
        ChooseWorkoutSettingsLabel      matlab.ui.control.Label
        SelectArduinoPortDropDown       matlab.ui.control.DropDown
        SelectArduinoPortDropDownLabel  matlab.ui.control.Label
        SelectDAQDeviceDropDown         matlab.ui.control.DropDown
        SelectDAQDeviceDropDownLabel    matlab.ui.control.Label
        StartWorkoutButton              matlab.ui.control.Button
        SearchforDAQsButton             matlab.ui.control.Button
        ConnectDataAcquisitionEquipmentLabel  matlab.ui.control.Label
        MainTab                         matlab.ui.container.Tab
        PacePanel                       matlab.ui.container.Panel
        SpO2EditField                   matlab.ui.control.NumericEditField
        SpO2EditFieldLabel              matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        PaceGauge                       matlab.ui.control.LinearGauge
        GaugeLabel                      matlab.ui.control.Label
        CurrentPaceLabel                matlab.ui.control.Label
        LiveFeedbackPanel               matlab.ui.container.Panel
        HRAxes                          matlab.ui.control.UIAxes
        CadenceAxes                     matlab.ui.control.UIAxes
        MetricsPanel                    matlab.ui.container.Panel
        GuidanceTextArea                matlab.ui.control.TextArea
        GuidanceTextAreaLabel           matlab.ui.control.Label
        TargetHeartRatebpmEditField     matlab.ui.control.NumericEditField
        TargetHeartRatebpmEditFieldLabel  matlab.ui.control.Label
        TargetCadencespmEditField       matlab.ui.control.NumericEditField
        TargetCadencespmEditFieldLabel  matlab.ui.control.Label
        HistoryTab                      matlab.ui.container.Tab
        YourWorkoutHistoryLabel         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
        %% DAQ objects and constants:
        selected_daq    % Selected NI DAQ device (e.g. "Dev15")
        selected_ard    % Selected Arduino port (e.g. "COM4")
        nidaq           % Currently running NI DAQ device
        nidaq_fs = 300; % NI DAQ sampling rate
        arduino         % Currently running arduino device
        ard_fs = 20;    % Arduino sampling rate
        d_hr            % Digital filt object for PPG data
        d_acc           % Digital filt object for acceleration data

        %% Data buffers (contantly updating):
        spo2            % Current blood oxygen value (float)
        ts_acc_buffer   % Current vector of timestamps for acc data
        x_acc_buffer    % Current vector of x-axis acceleration data
        y_acc_buffer    % Current vector of y-axis acceleration data
        ts_ppg          % Current vector of timestamps for ppg data
        ppg             % Current vector of ppg magnitude data
        heartrate       % Current heartrate value (float)
        
    end
    
    methods (Access = private)
        
        function results = mainFcn(app)
            %% NI DAQ Acquisition
            [data, ts, ~] = read(src, src.ScansAvailableFcnCount, OutputFormat='Matrix');
            try
                % Acquire data and update buffers:
                x_acc = (data(:,1) - 3/2) ./ 0.42;
                y_acc = (data(:,2) - 3/2) ./ 0.42;
                mag_acc = sqrt(x_data.^2 + y_data.^2); 

                app.x_acc_buffer = [app.x_acc_buffer; x_acc];
                app.y_acc_buffer = [app.y_acc_buffer; y_acc];
                app.ts_acc_buffer = [app.ts_acc_buffer; ts];
            catch ME
                fprintf("\n\tError in NIDAQ data acquisition:");
                fprintf("\n\tMessage: %s", ME.message);
            end

            %% Arduino Acquisition
            if app.arduino.NumBytesAvailable > 0
                try 
                    % Acquire data and update buffers:
                    line = readline(app.arduino);
                    ard_data = str2double(split(line, ','));
                    app.spo2 = ard_data(end);
                    app.ts_ppg = length(app.ppg)/app.ard_fs + app.ts_acc(end);
                    app.ppg = ard_data(1:end-1);
                    app.heartrate = heartRateFcn(app.ts_ppg, app.ppg, app.d_hr);
                catch ME
                    fprintf("\n\tError in Arduino data acquisition:");
                    fprintf("\n\tMessage: %s", ME.message);
                end
            end

            %% Process Data
            % here update plots, compute stuff, etc 

        end
        
        function createHRFilter(app)
            % Design a highpass filter for heartrate PPG data
            % Returns digital filter object d_hr
            % (it is more efficient to design the filter object once
            % than design it each time you use it)

            Fs = app.ard_fs;
            Fc = 0.4;
        
            app.d_hr = designfilt('highpassiir', ...
                'FilterOrder', 3, ...
                'HalfPowerFrequency', Fc, ...
                'SampleRate', Fs, ...
                'DesignMethod', 'Butter');
        end
        
        function createAccFilter(app)
            % Design a filter for the acceleration data
            % Returns digital filter object d_acc
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchforDAQsButton
        function searchForDAQs(app, event)
            % Search for DAQs and returns available COM (Serial) and DAQ
            % objects for user to select

            % Enable dropdown menus once button pressed:
            app.SelectDAQDeviceDropDown.Enable = "on";
            app.SelectArduinoPortDropDown.Enable = "on";

            % DAQ handler:
            avail_daqs = daqlist;
            if isempty(avail_daqs)
                app.ConnectedDAQStatusLabel.Text = 'No connected DAQ devices found.';
            else
                device_ids = avail_daqs.DeviceID;
                app.SelectDAQDeviceDropDown.Items = ["<Select>"; device_ids];
            end
            
            % Serial (Arduino) handler:
            avail_coms = serialportlist;
            if isempty(avail_coms)
                app.ArduinoPortStatusLabel.Text = 'No active COM (USB) ports found.';
            else
                app.SelectArduinoPortDropDown.Items = ["<Select>", avail_coms];
            end

            % Once user selects the DAQs, let them start the DAQs
            app.StartDAQsButton.Enable = "on";
        end

        % Button pushed function: StartDAQsButton
        function daqSetup(app, event)
            % This connects to the selected DAQs
            try
                app.nidaq = daq("ni");
                addinput(app.nidaq, app.selected_daq, "ai0", "Voltage");
                addinput(app.nidaq, app.selected_daq, "ai4", "Voltage");
                app.nidaq.Rate = app.nidaq_fs;
                app.nidaq.ScansAvailableFcnCount = app.nidaq_fs/10; % effective plot refresh rate
    
                app.arduino = serialport(app.selected_ard, 230400);
                configureTerminator(app.arduino, "LF");
                flush(app.arduino);
    
                app.DAQStartupStatus.FontColor = [0.23,0.67,0.20];
                app.DAQStartupStatus.Text = 'DAQs Connected Successfully';
                app.SearchforDAQsButton.Enable = "off";
                app.StartDAQsButton.Enable = "off";
                app.SelectArduinoPortDropDown.Enable = "off";
                app.SelectDAQDeviceDropDown.Enable = "off";
                app.StartWorkoutButton.Enable ="on";
            catch
                app.DAQStartupStatus.FontColor = [1 0 0];
                app.DAQStartupStatus.Text = "Error encountered: Check selected ports/devices";
            end
        end

        % Value changed function: SelectDAQDeviceDropDown
        function daqSelectionFcn(app, event)
            value = app.SelectDAQDeviceDropDown.Value;
            app.selected_daq = string(value);
        end

        % Value changed function: SelectArduinoPortDropDown
        function ardSelectionFcn(app, event)
            value = app.SelectArduinoPortDropDown.Value;
            app.selected_ard = string(value);
        end

        % Button pushed function: StartWorkoutButton
        function startWorkout(app, event)
            app.nidaq.ScansAvailableFcn = @(src, evt) mainFcn(app, src);
            start(app.nidaq, "continuous");
            createHRFilter(app);
            createAccFilter(app);
            app.StartWorkoutButton.Enable = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ConnectDataAcquisitionEquipmentLabel = uilabel(app.SetupTab);
            app.ConnectDataAcquisitionEquipmentLabel.Text = 'Connect Data Acquisition Equipment';
            app.SearchforDAQsButton = uibutton(app.SetupTab, 'push');
            app.SearchforDAQsButton.ButtonPushedFcn = createCallbackFcn(app, @searchForDAQs, true);
            app.SearchforDAQsButton.Text = 'Search for DAQs';
            app.StartWorkoutButton = uibutton(app.SetupTab, 'push');
            app.StartWorkoutButton.ButtonPushedFcn = createCallbackFcn(app, @startWorkout, true);
            app.StartWorkoutButton.Enable = 'off';
            app.StartWorkoutButton.Text = 'Start Workout';
            app.SelectDAQDeviceDropDownLabel = uilabel(app.SetupTab);
            app.SelectDAQDeviceDropDownLabel.Enable = 'off';
            app.SelectDAQDeviceDropDownLabel.Text = 'Select DAQ Device';
            app.SelectDAQDeviceDropDown = uidropdown(app.SetupTab);
            app.SelectDAQDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @daqSelectionFcn, true);
            app.SelectDAQDeviceDropDown.Enable = 'off';
            app.SelectDAQDeviceDropDown.Tooltip = {'Select the DAQ device to use (e.g. "Dev15").'};
            app.SelectArduinoPortDropDownLabel = uilabel(app.SetupTab);
            app.SelectArduinoPortDropDownLabel.Enable = 'off';
            app.SelectArduinoPortDropDownLabel.Text = 'Select Arduino Port';
            app.SelectArduinoPortDropDown = uidropdown(app.SetupTab);
            app.SelectArduinoPortDropDown.ValueChangedFcn = createCallbackFcn(app, @ardSelectionFcn, true);
            app.SelectArduinoPortDropDown.Enable = 'off';
            app.SelectArduinoPortDropDown.Tooltip = {'Select the COM (USB) port to which the Arduino is connected (e.g. "COM4").'};
            app.ChooseWorkoutSettingsLabel = uilabel(app.SetupTab);
            app.ChooseWorkoutSettingsLabel.Text = 'Choose Workout Settings';
            app.ArduinoPortStatusLabel = uilabel(app.SetupTab);
            app.ArduinoPortStatusLabel.Text = '';
            app.ConnectedDAQStatusLabel = uilabel(app.SetupTab);
            app.ConnectedDAQStatusLabel.Text = '';
            app.StartDAQsButton = uibutton(app.SetupTab, 'push');
            app.StartDAQsButton.ButtonPushedFcn = createCallbackFcn(app, @daqSetup, true);
            app.StartDAQsButton.Enable = 'off';
            app.StartDAQsButton.Text = 'Start DAQs';
            app.DAQStartupStatus = uilabel(app.SetupTab);
            app.DAQStartupStatus.Text = '';
            app.WelcometoPantheRunpleaseletscomeupwithadecentnameLabel = uilabel(app.SetupTab);
            app.WelcometoPantheRunpleaseletscomeupwithadecentnameLabel.Text = 'Welcome to PantheRun! (please let''s come up with a decent name)';
            app.maybeinthisareawecouldhavesomephotostextexplaininghowtowearconnectthesensorsLabel = uilabel(app.SetupTab);
            app.maybeinthisareawecouldhavesomephotostextexplaininghowtowearconnectthesensorsLabel.Text = 'maybe in this area we could have some photos/text explaining how to wear/connect the sensors?';
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.MetricsPanel = uipanel(app.MainTab);
            app.MetricsPanel.Title = 'Metrics';
            app.TargetCadencespmEditFieldLabel = uilabel(app.MetricsPanel);
            app.TargetCadencespmEditFieldLabel.Text = 'Target Cadence (spm)';
            app.TargetCadencespmEditField = uieditfield(app.MetricsPanel, 'numeric');
            app.TargetCadencespmEditField.Limits = [100 220];
            app.TargetCadencespmEditField.Value = 170;
            app.TargetHeartRatebpmEditFieldLabel = uilabel(app.MetricsPanel);
            app.TargetHeartRatebpmEditFieldLabel.Text = 'Target Heart Rate (bpm)';
            app.TargetHeartRatebpmEditField = uieditfield(app.MetricsPanel, 'numeric');
            app.TargetHeartRatebpmEditField.Limits = [60 210];
            app.TargetHeartRatebpmEditField.Value = 165;
            app.GuidanceTextAreaLabel = uilabel(app.MetricsPanel);
            app.GuidanceTextAreaLabel.Text = 'Guidance';
            app.GuidanceTextArea = uitextarea(app.MetricsPanel);
            app.GuidanceTextArea.Editable = 'off';
            app.GuidanceTextArea.Value = {'Increase cadence and take deeper breaths to lower heart rate'};
            app.LiveFeedbackPanel = uipanel(app.MainTab);
            app.LiveFeedbackPanel.Title = 'Live Feedback';
            app.CadenceAxes = uiaxes(app.LiveFeedbackPanel);
            title(app.CadenceAxes, 'Cadence')
            ylabel(app.CadenceAxes, 'spm')
            zlabel(app.CadenceAxes, 'Z')
            app.HRAxes = uiaxes(app.LiveFeedbackPanel);
            title(app.HRAxes, 'Heart Rate')
            xlabel(app.HRAxes, 'Time (s)')
            ylabel(app.HRAxes, 'bpm')
            zlabel(app.HRAxes, 'Z')
            app.PacePanel = uipanel(app.MainTab);
            app.PacePanel.Title = 'Pace';
            app.CurrentPaceLabel = uilabel(app.PacePanel);
            app.CurrentPaceLabel.Text = '8:30/mi';
            app.GaugeLabel = uilabel(app.PacePanel);
            app.GaugeLabel.Text = '';
            app.PaceGauge = uigauge(app.PacePanel, 'linear');
            app.PaceGauge.Limits = [4 16];
            app.PaceGauge.MajorTicks = [4 6 8 10 12 14 16];
            app.PaceGauge.MinorTicks = [5 7 9 11 13 15];
            app.PaceGauge.Value = 8.5;
            app.StatusLampLabel = uilabel(app.PacePanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.PacePanel);
            app.SpO2EditFieldLabel = uilabel(app.PacePanel);
            app.SpO2EditFieldLabel.Text = 'SpO2 (%)';
            app.SpO2EditField = uieditfield(app.PacePanel, 'numeric');
            app.SpO2EditField.Editable = 'off';
            app.SpO2EditField.Value = 97;
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.UITable = uitable(app.HistoryTab);
            app.UITable.ColumnName = {'Date'; 'Workout Time'; 'Average Heart Rate'; 'See More'};
            app.UITable.RowName = {};
            app.YourWorkoutHistoryLabel = uilabel(app.HistoryTab);
            app.YourWorkoutHistoryLabel.Text = 'Your Workout History';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BIOENG_2351_Project_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end